classdef liveJuliaSets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        RecSliderLabel  matlab.ui.control.Label
        RecSlider       matlab.ui.control.Slider
        ImcSliderLabel  matlab.ui.control.Label
        ImcSlider       matlab.ui.control.Slider
        c0000iLabel     matlab.ui.control.Label
    end


    methods (Access = private)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            image(app.UIAxes,juliaSet(400,400,0,200),'CDataMapping','scaled');
        end

        % Value changed function: RecSlider
        function RecSliderValueChanged(app, event)
            value = app.RecSlider.Value;
            image(app.UIAxes,juliaSet(400,400,value+1i*app.ImcSlider.Value,200),'CDataMapping','scaled');
            app.c0000iLabel.Text=strcat(num2str(value),"+",num2str(app.ImcSlider.Value),"i");
        end

        % Value changed function: ImcSlider
        function ImcSliderValueChanged(app, event)
            value = app.ImcSlider.Value;
            image(app.UIAxes,juliaSet(400,400,app.RecSlider.Value+1i*value,200),'CDataMapping','scaled');
            app.c0000iLabel.Text=strcat(num2str(app.RecSlider.Value),"+",num2str(value),"i");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Conjuntos de Julia')
            app.UIAxes.XLim = [0 400];
            app.UIAxes.YLim = [0 400];
            app.RecSliderLabel = uilabel(app.UIFigure);
            app.RecSliderLabel.Text = 'Re(c)';
            app.RecSlider = uislider(app.UIFigure);
            app.RecSlider.Limits = [-1 1];
            app.RecSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.RecSlider.ValueChangedFcn = createCallbackFcn(app, @RecSliderValueChanged, true);
            app.ImcSliderLabel = uilabel(app.UIFigure);
            app.ImcSliderLabel.Text = 'Im(c)';
            app.ImcSlider = uislider(app.UIFigure);
            app.ImcSlider.Limits = [-1 1];
            app.ImcSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.ImcSlider.ValueChangedFcn = createCallbackFcn(app, @ImcSliderValueChanged, true);
            app.c0000iLabel = uilabel(app.UIFigure);
            app.c0000iLabel.Text = 'c=0.0+0.0i';
        end
    end

    methods (Access = public)

        % Construct app
        function app = liveJuliaSets

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end